classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CellCountTab                    matlab.ui.container.Tab
        CalibrateButton                 matlab.ui.control.Button
        SensitivitySliderLabel          matlab.ui.control.Label
        SensitivitySlider               matlab.ui.control.Slider
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        RunCellCountButton              matlab.ui.control.Button
        LoadFolderButton                matlab.ui.control.Button
        SaveResultsButton               matlab.ui.control.Button
        PleaseselectyourimagefolderLabel  matlab.ui.control.Label
        FiletypeDropDownLabel           matlab.ui.control.Label
        FiletypeDropDown                matlab.ui.control.DropDown
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        AverageNumberofCellsEditFieldLabel  matlab.ui.control.Label
        AverageNumberofCellsEditField   matlab.ui.control.EditField
        CellLiteTab                     matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        LoadFolderButton_2              matlab.ui.control.Button
        Pleaseselectimagefolder1Label   matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        LoadFolderButton_3              matlab.ui.control.Button
        Pleaseselectimagefolder2Label   matlab.ui.control.Label
        FiletypeDropDown_2Label         matlab.ui.control.Label
        FiletypeDropDown_2              matlab.ui.control.DropDown
        FiletypeDropDown_3Label         matlab.ui.control.Label
        FiletypeDropDown_3              matlab.ui.control.DropDown
        RunCellLiteButton               matlab.ui.control.Button
        SaveResultsButton_3             matlab.ui.control.Button
        FilenameEditField_2Label        matlab.ui.control.Label
        FilenameEditField_2             matlab.ui.control.EditField
        AverageIntensityScoreEditFieldLabel  matlab.ui.control.Label
        AverageIntensityScoreEditField  matlab.ui.control.EditField
        CellTrackTab                    matlab.ui.container.Tab
        UnderDevelopmentLabel           matlab.ui.control.Label
    end


    properties (Access = public)
        % CellCount
        folderpath1 = '.' % COUNT-Default folder path
        folderpath2 = '.' % LITE-main directory
        folderpath3 = '.' % LITE-second directory
        extension1 = '*.jpg' % COUNT-Default reads JPEGS
        extension2 = '*.jpg' % LITE-main file type
        extension3 = '*.jpg' % LITE-second file type
        sensitivity1 = 1.0 % COUNT-Default sensitivity
        num_cells_list1 = [] % COUNT-Empty default
        avg_cells1 = 0 % COUNT-Default
        intensity_scores = 0 % LITE-Default
        avg_intensity_score = 0 % LITE-Default
        savename1 = 'cellcountresult' % COUNT-Default result file
        savename2 = 'cellliteresult' % LITE-Default result file
    end


    methods (Access = private)

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            CellCountInitial(app.folderpath1, app.extension1, app.sensitivity1);
        end

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            folderpath = uigetdir();
            app.folderpath1 = strcat(folderpath, '/');
        end

        % Value changed function: SensitivitySlider
        function SensitivitySliderValueChanged(app, event)
            value = app.SensitivitySlider.Value;
            app.sensitivity1 = value;
        end

        % Button pushed function: RunCellCountButton
        function RunCellCountButtonPushed(app, event)
            app.AverageNumberofCellsEditField.Value = 'RUNNING...';
            app.num_cells_list1 = CellCount(app.folderpath1, app.extension1, app.sensitivity1);
            app.avg_cells1 = mean(app.num_cells_list1);
            app.AverageNumberofCellsEditField.Value = int2str(app.avg_cells1);
        end

        % Value changed function: FiletypeDropDown
        function FiletypeDropDownValueChanged(app, event)
            value = app.FiletypeDropDown.Value;
            if strcmp(value, 'JPEG')
                app.extension1 = '*.jpg';
            end
            if strcmp(value, 'PNG')
                app.extension1 = '*.png';
            end
            if strcmp(value, 'GIF')
                app.extension1 = '*.gif';
            end
            if strcmp(value, 'TIF')
                app.extension1 = '*.tif';
            end
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            savepath = uigetdir();
            fid = fopen(strcat(savepath, '/', app.savename1),'wt');
            fprintf(fid,'Cell Count Results (same order as input): \n');
            fprintf(fid,'%f\n',app.num_cells_list1);
            fclose(fid);
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            value = app.FilenameEditField.Value;
            app.savename1 = strcat(value, '.txt');
        end

        % Value changed function: AverageNumberofCellsEditField
        function AverageNumberofCellsEditFieldValueChanged(app, event)
            value = app.AverageNumberofCellsEditField.Value;
        end

        % Value changing function: AverageNumberofCellsEditField
        function AverageNumberofCellsEditFieldValueChanging(app, event)
            value = app.AverageNumberofCellsEditField.Value;
        end

        % Button pushed function: LoadFolderButton_2
        function LoadFolderButton_2Pushed(app, event)
            folderpath = uigetdir();
            app.folderpath2 = strcat(folderpath, '/');
        end

        % Button pushed function: LoadFolderButton_3
        function LoadFolderButton_3Pushed(app, event)
            folderpath = uigetdir();
            app.folderpath3 = strcat(folderpath, '/');
        end

        % Value changed function: FiletypeDropDown_2
        function FiletypeDropDown_2ValueChanged(app, event)
            value = app.FiletypeDropDown_2.Value;
            if strcmp(value, 'JPEG')
                app.extension2 = '*.jpg';
            end
            if strcmp(value, 'PNG')
                app.extension2 = '*.png';
            end
            if strcmp(value, 'GIF')
                app.extension2 = '*.gif';
            end
            if strcmp(value, 'TIF')
                app.extension2 = '*.tif';
            end
        end

        % Value changed function: FiletypeDropDown_3
        function FiletypeDropDown_3ValueChanged(app, event)
            value = app.FiletypeDropDown_3.Value;
            if strcmp(value, 'JPEG')
                app.extension3 = '*.jpg';
            end
            if strcmp(value, 'PNG')
                app.extension3 = '*.png';
            end
            if strcmp(value, 'GIF')
                app.extension3 = '*.gif';
            end
            if strcmp(value, 'TIF')
                app.extension3 = '*.tif';
            end
        end

        % Button pushed function: RunCellLiteButton
        function RunCellLiteButtonPushed(app, event)
            app.intensity_scores = CellLite(app.folderpath2, app.folderpath3, app.extension2, app.extension3, 2); % hardcoded sensitivity
            app.avg_intensity_score = mean(app.intensity_scores);
            app.AverageIntensityScoreEditField.Value = num2str(app.avg_intensity_score);
        end

        % Value changing function: AverageIntensityScoreEditField
        function AverageIntensityScoreEditFieldValueChanging(app, event)
            value = app.AverageIntensityScoreEditField.Value;
        end

        % Value changed function: FilenameEditField_2
        function FilenameEditField_2ValueChanged(app, event)
            value = app.FilenameEditField_2.Value;
            app.savename2 = strcat(value, '.txt');
        end

        % Button pushed function: SaveResultsButton_3
        function SaveResultsButton_3Pushed(app, event)
            savepath = uigetdir();
            fid = fopen(strcat(savepath, '/', app.savename2),'wt');
            fprintf(fid,'Cell Lite Results (same order as input): \n');
            fprintf(fid,'%f\n',app.intensity_scores);
            fclose(fid);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CellCountTab = uitab(app.TabGroup);
            app.CellCountTab.Title = 'CellCount';
            app.CellCountTab.Units = 'pixels';
            app.CalibrateButton = uibutton(app.CellCountTab, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.SensitivitySliderLabel = uilabel(app.CellCountTab);
            app.SensitivitySliderLabel.Text = 'Sensitivity';
            app.SensitivitySlider = uislider(app.CellCountTab);
            app.SensitivitySlider.Limits = [1 11];
            app.SensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @SensitivitySliderValueChanged, true);
            app.SensitivitySlider.Value = 1;
            app.Label = uilabel(app.CellCountTab);
            app.Label.Text = 'CellCount counts the number of cells in each of the images as well as the average in the selected folder.';
            app.Label_2 = uilabel(app.CellCountTab);
            app.Label_2.Text = 'For best results, calibrate the sensitivity of CellCount for your images. Then run the program.';
            app.RunCellCountButton = uibutton(app.CellCountTab, 'push');
            app.RunCellCountButton.ButtonPushedFcn = createCallbackFcn(app, @RunCellCountButtonPushed, true);
            app.RunCellCountButton.Text = 'Run CellCount';
            app.LoadFolderButton = uibutton(app.CellCountTab, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.SaveResultsButton = uibutton(app.CellCountTab, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.PleaseselectyourimagefolderLabel = uilabel(app.CellCountTab);
            app.PleaseselectyourimagefolderLabel.Text = 'Please select your image folder:';
            app.FiletypeDropDownLabel = uilabel(app.CellCountTab);
            app.FiletypeDropDownLabel.Text = 'File type';
            app.FiletypeDropDown = uidropdown(app.CellCountTab);
            app.FiletypeDropDown.Items = {'JPEG', 'PNG', 'TIF', 'GIF'};
            app.FiletypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FiletypeDropDownValueChanged, true);
            app.FiletypeDropDown.Value = 'JPEG';
            app.FilenameEditFieldLabel = uilabel(app.CellCountTab);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.CellCountTab, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.AverageNumberofCellsEditFieldLabel = uilabel(app.CellCountTab);
            app.AverageNumberofCellsEditFieldLabel.Text = 'Average Number of Cells';
            app.AverageNumberofCellsEditField = uieditfield(app.CellCountTab, 'text');
            app.AverageNumberofCellsEditField.ValueChangedFcn = createCallbackFcn(app, @AverageNumberofCellsEditFieldValueChanged, true);
            app.AverageNumberofCellsEditField.ValueChangingFcn = createCallbackFcn(app, @AverageNumberofCellsEditFieldValueChanging, true);
            app.CellLiteTab = uitab(app.TabGroup);
            app.CellLiteTab.Title = 'CellLite';
            app.CellLiteTab.Units = 'pixels';
            app.Label_3 = uilabel(app.CellLiteTab);
            app.Label_3.Text = 'CellLite gives intensity scores for images with fluorescent cells relative to the number of cells.';
            app.LoadFolderButton_2 = uibutton(app.CellLiteTab, 'push');
            app.LoadFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButton_2Pushed, true);
            app.LoadFolderButton_2.Text = 'Load Folder';
            app.Pleaseselectimagefolder1Label = uilabel(app.CellLiteTab);
            app.Pleaseselectimagefolder1Label.Text = 'Please select image folder #1:';
            app.Label_4 = uilabel(app.CellLiteTab);
            app.Label_4.Text = 'Image folder #1 should contain all of the images of interest.';
            app.Label_5 = uilabel(app.CellLiteTab);
            app.Label_5.Text = 'Image folder #2 should contain all corresponding control (non-fluorescent) images.';
            app.LoadFolderButton_3 = uibutton(app.CellLiteTab, 'push');
            app.LoadFolderButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButton_3Pushed, true);
            app.LoadFolderButton_3.Text = 'Load Folder';
            app.Pleaseselectimagefolder2Label = uilabel(app.CellLiteTab);
            app.Pleaseselectimagefolder2Label.Text = 'Please select image folder #2:';
            app.FiletypeDropDown_2Label = uilabel(app.CellLiteTab);
            app.FiletypeDropDown_2Label.Text = 'File type';
            app.FiletypeDropDown_2 = uidropdown(app.CellLiteTab);
            app.FiletypeDropDown_2.Items = {'JPEG', 'PNG', 'TIF', 'GIF'};
            app.FiletypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FiletypeDropDown_2ValueChanged, true);
            app.FiletypeDropDown_2.Value = 'JPEG';
            app.FiletypeDropDown_3Label = uilabel(app.CellLiteTab);
            app.FiletypeDropDown_3Label.Text = 'File type';
            app.FiletypeDropDown_3 = uidropdown(app.CellLiteTab);
            app.FiletypeDropDown_3.Items = {'JPEG', 'PNG', 'TIF', 'GIF'};
            app.FiletypeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @FiletypeDropDown_3ValueChanged, true);
            app.FiletypeDropDown_3.Value = 'JPEG';
            app.RunCellLiteButton = uibutton(app.CellLiteTab, 'push');
            app.RunCellLiteButton.ButtonPushedFcn = createCallbackFcn(app, @RunCellLiteButtonPushed, true);
            app.RunCellLiteButton.Text = 'Run CellLite';
            app.SaveResultsButton_3 = uibutton(app.CellLiteTab, 'push');
            app.SaveResultsButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_3Pushed, true);
            app.SaveResultsButton_3.Text = 'Save Results';
            app.FilenameEditField_2Label = uilabel(app.CellLiteTab);
            app.FilenameEditField_2Label.Text = 'Filename';
            app.FilenameEditField_2 = uieditfield(app.CellLiteTab, 'text');
            app.FilenameEditField_2.ValueChangedFcn = createCallbackFcn(app, @FilenameEditField_2ValueChanged, true);
            app.AverageIntensityScoreEditFieldLabel = uilabel(app.CellLiteTab);
            app.AverageIntensityScoreEditFieldLabel.Text = 'Average Intensity Score';
            app.AverageIntensityScoreEditField = uieditfield(app.CellLiteTab, 'text');
            app.AverageIntensityScoreEditField.ValueChangingFcn = createCallbackFcn(app, @AverageIntensityScoreEditFieldValueChanging, true);
            app.CellTrackTab = uitab(app.TabGroup);
            app.CellTrackTab.Title = 'CellTrack';
            app.CellTrackTab.Units = 'pixels';
            app.UnderDevelopmentLabel = uilabel(app.CellTrackTab);
            app.UnderDevelopmentLabel.Text = 'Under Development';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end